classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Button                        matlab.ui.control.Button
        JogmmEditField                matlab.ui.control.NumericEditField
        JogmmEditFieldLabel           matlab.ui.control.Label
        YLabel_2                      matlab.ui.control.Label
        PLabel                        matlab.ui.control.Label
        RLabel                        matlab.ui.control.Label
        ZLabel                        matlab.ui.control.Label
        YLabel                        matlab.ui.control.Label
        XLabel                        matlab.ui.control.Label
        RobotEndEffectorLabel         matlab.ui.control.Label
        ZButton                       matlab.ui.control.Button
        ZButton_2                     matlab.ui.control.Button
        YButton                       matlab.ui.control.Button
        YButton_2                     matlab.ui.control.Button
        XButton_2                     matlab.ui.control.Button
        XButton                       matlab.ui.control.Button
        TeachLabel                    matlab.ui.control.Label
        Link7Slider                   matlab.ui.control.Slider
        Link7SliderLabel              matlab.ui.control.Label
        Link6Slider                   matlab.ui.control.Slider
        Link6SliderLabel              matlab.ui.control.Label
        Link5Slider                   matlab.ui.control.Slider
        Link5SliderLabel              matlab.ui.control.Label
        Link4Slider                   matlab.ui.control.Slider
        Link4SliderLabel              matlab.ui.control.Label
        Link3Slider                   matlab.ui.control.Slider
        Link3SliderLabel              matlab.ui.control.Label
        Link2Slider                   matlab.ui.control.Slider
        Link2SliderLabel              matlab.ui.control.Label
        Link1Slider                   matlab.ui.control.Slider
        Link1SliderLabel              matlab.ui.control.Label
        SelectRobotButtonGroup        matlab.ui.container.ButtonGroup
        ProgramButton                 matlab.ui.control.Button
        RunningLamp                   matlab.ui.control.Lamp
        RunningLampLabel              matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        Image2                        matlab.ui.control.Image
        PandaButton                   matlab.ui.control.RadioButton
        LinearUR5Button               matlab.ui.control.RadioButton
        DegreestoRadiansCalculatorLabel  matlab.ui.control.Label
        AngleinRadiansEditFieldLabel  matlab.ui.control.Label
        AngleinRadiansEditField       matlab.ui.control.NumericEditField
        AngleinDegreesEditFieldLabel  matlab.ui.control.Label
        AngleinDegreesEditField       matlab.ui.control.NumericEditField
        RobotJoggingLabel             matlab.ui.control.Label
    end

    
    properties (Access = public)
        robot;
        jogDist;
        estop;
        linearUR5;
        panda;
        robotXYZ;
        robotRPY;
        robotJoints;
        robotTr;
        qlim;
        running;
        flicker = 3;
        count;
        state; 
        first;

        usingHardware;
    end
    
    properties (Access = private)
        arduinoObj;
        estopPin = 'D2'; % Adjust as per your connection
        startPin = 'D3'; % Adjust as per your connection % Description

        redPin = 'D9';  % Pin connected to the Red component of the LED.
        greenPin = 'D10';  % Pin connected to the Green component of the LED.
        bluePin = 'D11';  % Pin connected to the Blue component of the LED.

        lastEstopPressTime;
        lastStartPressTime;
        debounceDelay = 0.5; % seconds
    end
    
    methods (Access = public)
        function UpdateGUI(app) 
            app.robotJoints = app.robot.model.getpos();
            app.robotTr = app.robot.model.fkine(app.robotJoints);
            app.robotTr = app.robotTr.T;
            app.robotXYZ = app.robotTr(1:3,4)';
            app.robotRPY = tr2rpy(app.robotTr);
            app.robotRPY = rad2deg(app.robotRPY);

            app.XLabel.Text = ['X = ', num2str(app.robotTr(1, 4))];
            app.YLabel.Text = ['Y = ', num2str(app.robotTr(2, 4))];
            app.ZLabel.Text = ['Z = ', num2str(app.robotTr(3, 4))];
            app.RLabel.Text = ['R = ', num2str(app.robotRPY(1))];
            app.PLabel.Text = ['P = ', num2str(app.robotRPY(2))];
            app.YLabel_2.Text = ['Y = ', num2str(app.robotRPY(3))];

            if mod(app.count,3) == 0
                app.flicker = ~app.flicker;
            end

            if ~app.running && ~app.estop && app.first
                app.state = 1; % program not started, estop disengaged
            elseif app.running && ~app.estop
                app.state = 2; % Program running
            elseif app.estop
                app.state = 3; % E-Stop engaged
                app.running = false;
            elseif ~app.running && ~app.estop
                app.state = 4; % E-Stop diengaged waiting for program to be resumed
            end
            
            if app.usingHardware
                app.ReadButtonStates();
            end

            app.UpdateStates();

            app.count = app.count + 1;
        end

        function ReadButtonStates(app)
            estopState = readDigitalPin(app.arduinoObj, app.estopPin);
            startState = readDigitalPin(app.arduinoObj, app.startPin);
            
            current_time = datetime('now');
            
            % eStop button logic
            if estopState == 0
                if isempty(app.lastEstopPressTime)
                    app.lastEstopPressTime = current_time;
                elseif (current_time - app.lastEstopPressTime) * 24 * 3600 > app.debounceDelay
                    app.estop = ~app.estop;
                    if app.estop
                        app.running = false;
                    end
                    app.lastEstopPressTime = []; % Clear the timestamp
                end
            else
                app.lastEstopPressTime = []; % Clear the timestamp if button is not pressed
            end
            
            % Start button logic
            if startState == 0 && ~app.estop
                if isempty(app.lastStartPressTime)
                    app.lastStartPressTime = current_time;
                elseif (current_time - app.lastStartPressTime) * 24 * 3600 > app.debounceDelay
                    app.running = ~app.running;
                    app.lastStartPressTime = []; % Clear the timestamp
                end
            else
                app.lastStartPressTime = []; % Clear the timestamp if button is not pressed
            end
        end

        function [qMatrix, model] = GetSliderValues(app)
            qMatrix(1) = app.Link1Slider.Value;
            qMatrix(2) = app.Link2Slider.Value;
            qMatrix(3) = app.Link3Slider.Value;
            qMatrix(4) = app.Link4Slider.Value;
            qMatrix(5) = app.Link5Slider.Value;
            qMatrix(6) = app.Link6Slider.Value;
            qMatrix(7) = app.Link7Slider.Value;

            model = app.robot;
        end

        function UpdateSliders(app)
            app.Link1Slider.Value = app.robotJoints(1);
            app.Link2Slider.Value = app.robotJoints(2);
            app.Link3Slider.Value = app.robotJoints(3);
            app.Link4Slider.Value = app.robotJoints(4);
            app.Link5Slider.Value = app.robotJoints(5);
            app.Link6Slider.Value = app.robotJoints(6);
            app.Link7Slider.Value = app.robotJoints(7);
        end


        function StepRobot(app, plane)
            % newJointAngles = app.robot.model.ikcon(app.robotTr);
            newJointAngles = ResolvedMotionRateControl(app.robot,app.robotTr, plane);
            for i = 1:7
                app.robotJoints(i) = newJointAngles(i);
            end
            app.UpdateSliders();
        end
     
        function inLimits = AnglesInQLims(app)
            for i = 1:length(app.robotJoints)
                if app.robotJoints(i) < app.robot.model.qlim(i,1) || app.robotJoints(i) > app.robot.model.qlim(i,2)
                    inLimits = false;
                    return;
                end
            end
            inLimits = true;
            return
        end
        
        function UpdateStates(app)
            switch app.state
                case 1
                    app.ProgramButton.Text = 'Click to Start';
                    app.ProgramButton.BackgroundColor = [0.00,0.45,0.74];
                    app.RunningLamp.Color = [0.00,0.45,0.74];
                    if app.flicker
                        app.RunningLamp.Color = [0.07,0.62,1.00];
                    end
                    app.Button.BackgroundColor = [0.94,0.94,0.94];
                    if app.usingHardware
                        writeDigitalPin(app.arduinoObj, app.redPin, 0);    % Red ON
                        writeDigitalPin(app.arduinoObj, app.greenPin, 0); % Green half ON to make orange
                        writeDigitalPin(app.arduinoObj, app.bluePin, 1);   % Blue OFF
                    end
                case 2
                    app.ProgramButton.Text = 'Robots Running';
                    app.ProgramButton.BackgroundColor = [0.39,0.83,0.07];
                    app.RunningLamp.Color = [0 1 0];
                    if app.flicker
                        app.RunningLamp.Color = [0.39,0.83,0.07];
                    end
                    app.Button.BackgroundColor = [0.94,0.94,0.94];
                    if app.usingHardware
                        writeDigitalPin(app.arduinoObj, app.redPin, 0);    % Red ON
                        writeDigitalPin(app.arduinoObj, app.greenPin, 1); % Green half ON to make orange
                        writeDigitalPin(app.arduinoObj, app.bluePin, 0);   % Blue OFF
                    end
                case 3
                    app.Button.BackgroundColor = [1 0 0];
                    app.ProgramButton.BackgroundColor = [1 0 0];
                    app.RunningLamp.Color = [1 0 0];
                    app.ProgramButton.Text = 'E-Stop Engaged';
                    if app.usingHardware
                        writeDigitalPin(app.arduinoObj, app.redPin, 1);    % Red ON
                        writeDigitalPin(app.arduinoObj, app.greenPin, 0); % Green half ON to make orange
                        writeDigitalPin(app.arduinoObj, app.bluePin, 0);   % Blue OFF
                    end
                case 4
                    app.ProgramButton.Text = 'Click to Resume';
                    app.ProgramButton.BackgroundColor = [0.00,0.45,0.74];
                    app.RunningLamp.Color = [0.00,0.45,0.74];
                    if app.flicker
                        app.RunningLamp.Color = [0.07,0.62,1.00];
                    end
                    app.Button.BackgroundColor = [0.94,0.94,0.94];
                    if app.usingHardware
                        writeDigitalPin(app.arduinoObj, app.redPin, 0);    % Red ON
                        writeDigitalPin(app.arduinoObj, app.greenPin, 0); % Green half ON to make orange
                        writeDigitalPin(app.arduinoObj, app.bluePin, 1);   % Blue OFF
                    end
            end

            if app.running || app.estop
                if app.AnglesInQLims()
                    app.UpdateSliders();
                end
                app.ProgramButton.Enable = 'off';
                app.Link1Slider.Enable = 'off';
                app.Link2Slider.Enable = 'off';
                app.Link3Slider.Enable = 'off';
                app.Link4Slider.Enable = 'off';
                app.Link5Slider.Enable = 'off';
                app.Link6Slider.Enable = 'off';
                app.Link7Slider.Enable = 'off';
                app.XButton.Enable = 'off';
                app.XButton_2.Enable = 'off';
                app.YButton.Enable = 'off';
                app.YButton_2.Enable = 'off';
                app.ZButton.Enable = 'off';
                app.ZButton_2.Enable = 'off';
            else
                app.ProgramButton.Enable = 'on';
                app.Link1Slider.Enable = 'on';
                app.Link2Slider.Enable = 'on';
                app.Link3Slider.Enable = 'on';
                app.Link4Slider.Enable = 'on';
                app.Link5Slider.Enable = 'on';
                app.Link6Slider.Enable = 'on';
                app.Link7Slider.Enable = 'on';
                app.XButton.Enable = 'on';
                app.XButton_2.Enable = 'on';
                app.YButton.Enable = 'on';
                app.YButton_2.Enable = 'on';
                app.ZButton.Enable = 'on';
                app.ZButton_2.Enable = 'on';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, linearUR5, panda, hardware, comPort)
            disp('start up boi')

            app.usingHardware = hardware;

            if app.usingHardware
                app.arduinoObj = arduino(comPort,'Mega2560'); % Check port number
    
                configurePin(app.arduinoObj, app.estopPin, 'Pullup');  % Configure as pull-up input.
                configurePin(app.arduinoObj, app.startPin, 'Pullup');  % Configure as pull-up input.
                configurePin(app.arduinoObj, app.redPin, 'DigitalOutput');
                configurePin(app.arduinoObj, app.greenPin, 'DigitalOutput');
                configurePin(app.arduinoObj, app.bluePin, 'DigitalOutput');
            end

            app.state = 1;
            app.first = true;
            app.count = 1;

            app.estop = false;
            app.running = false;

            app.jogDist = 0.005;

            app.JogmmEditField.Value = app.jogDist * 1000;

            app.linearUR5 = linearUR5;
            app.panda = panda;

            app.robot = app.panda;

            app.Link1Slider.Limits = app.robot.model.qlim(1,:);
            app.Link2Slider.Limits = app.robot.model.qlim(2,:);
            app.Link3Slider.Limits = app.robot.model.qlim(3,:);
            app.Link4Slider.Limits = app.robot.model.qlim(4,:);
            app.Link5Slider.Limits = app.robot.model.qlim(5,:);
            app.Link6Slider.Limits = app.robot.model.qlim(6,:);
            app.Link7Slider.Limits = app.robot.model.qlim(7,:);
           
        end

        % Value changed function: AngleinRadiansEditField
        function AngleinRadiansEditFieldValueChanged(app, event)
            value = app.AngleinRadiansEditField.Value;
            app.AngleinDegreesEditField.Value = value * (180/pi);
        end

        % Value changed function: AngleinDegreesEditField
        function AngleinDegreesEditFieldValueChanged(app, event)
            value = app.AngleinDegreesEditField.Value;
            app.AngleinRadiansEditField.Value = value * (pi/180);
        end

        % Callback function
        function XEditField_11ValueChanged(app, event)
            value = app.XEditField_11.Value;
            app.baseX = value;
        end

        % Callback function
        function YEditField_11ValueChanged(app, event)
            value = app.YEditField_11.Value;
            app.baseY = value;
        end

        % Callback function
        function ZEditField_11ValueChanged(app, event)
            value = app.ZEditField_11.Value;
            app.baseZ = value;
        end

        % Selection changed function: SelectRobotButtonGroup
        function SelectRobotButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectRobotButtonGroup.SelectedObject.Text;
            if strcmp(selectedButton, 'Panda')
                app.robot = app.panda;
            else
                app.robot = app.linearUR5;
            end
            app.Link1Slider.Limits = app.robot.model.qlim(1,:);
            app.Link2Slider.Limits = app.robot.model.qlim(2,:);
            app.Link3Slider.Limits = app.robot.model.qlim(3,:);
            app.Link4Slider.Limits = app.robot.model.qlim(4,:);
            app.Link5Slider.Limits = app.robot.model.qlim(5,:);
            app.Link6Slider.Limits = app.robot.model.qlim(6,:);
            app.Link7Slider.Limits = app.robot.model.qlim(7,:);

            disp(app.robot);
            app.UpdateGUI();
        end

        % Value changed function: Link1Slider
        function Link1SliderValueChanged(app, event)
            app.GetSliderValues();
            disp('here')
        end

        % Value changed function: Link2Slider
        function Link2SliderValueChanged(app, event)
            app.GetSliderValues();
        end

        % Value changed function: Link3Slider
        function Link3SliderValueChanged(app, event)
            app.GetSliderValues();
        end

        % Value changed function: Link4Slider
        function Link4SliderValueChanged(app, event)
            app.GetSliderValues();
        end

        % Value changed function: Link5Slider
        function Link5SliderValueChanged(app, event)
            app.GetSliderValues();
        end

        % Value changed function: Link6Slider
        function Link6SliderValueChanged(app, event)
            app.GetSliderValues();
        end

        % Value changed function: Link7Slider
        function Link7SliderValueChanged(app, event)
            app.GetSliderValues();
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            app.robotTr(1,4) = app.robotTr(1,4) - app.jogDist;
            app.StepRobot('x')
        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
            app.robotTr(1,4) = app.robotTr(1,4) + app.jogDist;
            app.StepRobot('x')
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            app.robotTr(2,4) = app.robotTr(2,4) - app.jogDist;
            app.StepRobot('y');
        end

        % Button pushed function: YButton_2
        function YButton_2Pushed(app, event)
            app.robotTr(2,4) = app.robotTr(2,4) + app.jogDist;
            app.StepRobot('y');
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
            app.robotTr(3,4) = app.robotTr(3,4) - app.jogDist;
            app.StepRobot('z');
        end

        % Button pushed function: ZButton_2
        function ZButton_2Pushed(app, event)
            app.robotTr(3,4) = app.robotTr(3,4) + app.jogDist;
            app.StepRobot('z');
        end

        % Value changed function: JogmmEditField
        function JogmmEditFieldValueChanged(app, event)
            value = app.JogmmEditField.Value;
            app.jogDist = value/1000;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.estop = ~app.estop;
            
        end

        % Button pushed function: ProgramButton
        function ProgramButtonPushed(app, event)
            app.running = ~app.running;
            app.first = false;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % if app.usingHardware
            %     disp('Closing the app...');
            %     if isvalid(app.arduinoObj)
            %         disp('Disconnecting Arduino...');
            %         clear app.arduinoObj;  % Clear the Arduino connection
            %     end
            % end
            delete(app);  % Close the app

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RobotJoggingLabel = uilabel(app.UIFigure);
            app.RobotJoggingLabel.Text = 'Robot Jogging';
            app.AngleinDegreesEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngleinDegreesEditField.ValueChangedFcn = createCallbackFcn(app, @AngleinDegreesEditFieldValueChanged, true);
            app.AngleinDegreesEditFieldLabel = uilabel(app.UIFigure);
            app.AngleinDegreesEditFieldLabel.Text = 'Angle in Degrees';
            app.AngleinRadiansEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngleinRadiansEditField.ValueChangedFcn = createCallbackFcn(app, @AngleinRadiansEditFieldValueChanged, true);
            app.AngleinRadiansEditFieldLabel = uilabel(app.UIFigure);
            app.AngleinRadiansEditFieldLabel.Text = 'Angle in Radians';
            app.DegreestoRadiansCalculatorLabel = uilabel(app.UIFigure);
            app.DegreestoRadiansCalculatorLabel.Text = 'Degrees to Radians Calculator';
            app.SelectRobotButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectRobotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectRobotButtonGroupSelectionChanged, true);
            app.SelectRobotButtonGroup.Title = 'Select Robot';
            app.LinearUR5Button = uiradiobutton(app.SelectRobotButtonGroup);
            app.LinearUR5Button.Text = 'LinearUR5';
            app.PandaButton = uiradiobutton(app.SelectRobotButtonGroup);
            app.PandaButton.Text = 'Panda';
            app.PandaButton.Value = true;
            app.Image2 = uiimage(app.SelectRobotButtonGroup);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '@Panda', 'panda.jpg');
            app.Image = uiimage(app.SelectRobotButtonGroup);
            app.Image.ImageSource = fullfile(pathToMLAPP, '@LinearUR5custom', 'ur5.jpg');
            app.RunningLampLabel = uilabel(app.SelectRobotButtonGroup);
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.SelectRobotButtonGroup);
            app.ProgramButton = uibutton(app.SelectRobotButtonGroup, 'push');
            app.ProgramButton.ButtonPushedFcn = createCallbackFcn(app, @ProgramButtonPushed, true);
            app.ProgramButton.Text = 'ProgramButton';
            app.Link1SliderLabel = uilabel(app.UIFigure);
            app.Link1SliderLabel.Text = 'Link 1';
            app.Link1Slider = uislider(app.UIFigure);
            app.Link1Slider.Limits = [-1 1];
            app.Link1Slider.ValueChangedFcn = createCallbackFcn(app, @Link1SliderValueChanged, true);
            app.Link2SliderLabel = uilabel(app.UIFigure);
            app.Link2SliderLabel.Text = 'Link 2';
            app.Link2Slider = uislider(app.UIFigure);
            app.Link2Slider.Limits = [-1 1];
            app.Link2Slider.ValueChangedFcn = createCallbackFcn(app, @Link2SliderValueChanged, true);
            app.Link3SliderLabel = uilabel(app.UIFigure);
            app.Link3SliderLabel.Text = 'Link 3';
            app.Link3Slider = uislider(app.UIFigure);
            app.Link3Slider.Limits = [-1 1];
            app.Link3Slider.ValueChangedFcn = createCallbackFcn(app, @Link3SliderValueChanged, true);
            app.Link4SliderLabel = uilabel(app.UIFigure);
            app.Link4SliderLabel.Text = 'Link 4';
            app.Link4Slider = uislider(app.UIFigure);
            app.Link4Slider.Limits = [-1 1];
            app.Link4Slider.ValueChangedFcn = createCallbackFcn(app, @Link4SliderValueChanged, true);
            app.Link5SliderLabel = uilabel(app.UIFigure);
            app.Link5SliderLabel.Text = 'Link 5';
            app.Link5Slider = uislider(app.UIFigure);
            app.Link5Slider.Limits = [-1 1];
            app.Link5Slider.ValueChangedFcn = createCallbackFcn(app, @Link5SliderValueChanged, true);
            app.Link6SliderLabel = uilabel(app.UIFigure);
            app.Link6SliderLabel.Text = 'Link 6';
            app.Link6Slider = uislider(app.UIFigure);
            app.Link6Slider.Limits = [-1 1];
            app.Link6Slider.ValueChangedFcn = createCallbackFcn(app, @Link6SliderValueChanged, true);
            app.Link7SliderLabel = uilabel(app.UIFigure);
            app.Link7SliderLabel.Text = 'Link 7';
            app.Link7Slider = uislider(app.UIFigure);
            app.Link7Slider.Limits = [-1 1];
            app.Link7Slider.ValueChangedFcn = createCallbackFcn(app, @Link7SliderValueChanged, true);
            app.TeachLabel = uilabel(app.UIFigure);
            app.TeachLabel.Text = 'Teach';
            app.XButton = uibutton(app.UIFigure, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = '- X';
            app.XButton_2 = uibutton(app.UIFigure, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Text = '+ X';
            app.YButton_2 = uibutton(app.UIFigure, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YButton_2Pushed, true);
            app.YButton_2.Text = '+ Y';
            app.YButton = uibutton(app.UIFigure, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = '- Y';
            app.ZButton_2 = uibutton(app.UIFigure, 'push');
            app.ZButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZButton_2Pushed, true);
            app.ZButton_2.Text = '+ Z';
            app.ZButton = uibutton(app.UIFigure, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = '- Z';
            app.RobotEndEffectorLabel = uilabel(app.UIFigure);
            app.RobotEndEffectorLabel.Text = 'Robot End Effector';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X = ';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y = ';
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z = ';
            app.RLabel = uilabel(app.UIFigure);
            app.RLabel.Text = 'R = ';
            app.PLabel = uilabel(app.UIFigure);
            app.PLabel.Text = 'P = ';
            app.YLabel_2 = uilabel(app.UIFigure);
            app.YLabel_2.Text = 'Y = ';
            app.JogmmEditFieldLabel = uilabel(app.UIFigure);
            app.JogmmEditFieldLabel.Text = 'Jog (mm)';
            app.JogmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.JogmmEditField.ValueChangedFcn = createCallbackFcn(app, @JogmmEditFieldValueChanged, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'estop.png');
            app.Button.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end